classdef MaggotScorer_quick < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        ObjectnumberDropDownLabel    matlab.ui.control.Label
        ObjectnumberDropDown         matlab.ui.control.DropDown
        TimestampDropDownLabel       matlab.ui.control.Label
        TimestampDropDown            matlab.ui.control.DropDown
        CenterPanel                  matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        TimeSliderLabel              matlab.ui.control.Label
        TimeSlider                   matlab.ui.control.Slider
        RightPanel                   matlab.ui.container.Panel
        BehaviourButtonGroup         matlab.ui.container.ButtonGroup
        RollButton                   matlab.ui.control.RadioButton
        CBendButton                  matlab.ui.control.RadioButton
        BehaviourButton              matlab.ui.control.RadioButton
        BehaviournameEditFieldLabel  matlab.ui.control.Label
        BehaviournameEditField       matlab.ui.control.EditField
        AddBehaviourButton           matlab.ui.control.Button
        PlayButton                   matlab.ui.control.StateButton
        BackButton                   matlab.ui.control.StateButton
        ForwardOne                   matlab.ui.control.Button
        BackOne                      matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        data % data of class dataStruct
        current_data % instance of loaded data, of class dataStruct
        current_ani 
        current_frame
        times
        params % params, as defined by yaml file
        mainplot %
        scores
        scores_all
        scoreplot
        textplot
    end
    
    methods (Access = private)
        
        %
        function [x,y] = unpackKerr_xy(app,kerrstring,npts,x_point,y_point)
            if isempty(kerrstring)
                x = nan;
                y = nan;
                return
            end
            
            ccstr = uint8(kerrstring) - '0';
            npt = npts;
            
            bc = reshape([bitand(bitshift(ccstr, -4), 3); bitand(bitshift(ccstr, -2), 3); bitand(ccstr, 3)], 1, []);
            bc = bc(1:(npt));
            
            dxx = sum([-1; 1].*(bc == [0;1]), 1);
            dyy = sum([-1; 1].*(bc == [2;3]), 1);
            
            x = [0 cumsum(dxx) 0] + x_point;
            y = [0 cumsum(dyy) 0] + y_point;
        end
        
        %
        function update_main_plot(app,linehandle,ani_data,frame)
            [x,y] = unpackKerr_xy(app,ani_data.outline{frame},...
                ani_data.npts(frame),...
                ani_data.x_cont(frame),...
                ani_data.y_cont(frame));
    
            if isnan(x)
                set(app.UIAxes,'XLim',[-50 50]+ani_data.x_cent(frame),'YLim',[-50 50]+ani_data.y_cent(frame))
            elseif any(any(~[app.UIAxes.XLim(1) < x', x' < app.UIAxes.XLim(2)])) | any(any(~[app.UIAxes.YLim(1) < y', y' < app.UIAxes.YLim(2)]))
                mx = mean(x);
                my = mean(y);
                set(app.UIAxes,'XLim',[-80 80]+mx,'YLim',[-80 80]+my) 
            end     
            
            set(linehandle,'XData',x,'YData',y);
            app.textplot.String = string(app.times(app.current_frame));
        end
        
        function update_slider(app)
            set(app.TimeSlider,'Limits',[min(app.current_ani.time),max(app.current_ani.time)],...
                'Value',app.times(1));
            TimeSliderValueChanged(app)
        end
    
        function update_textplot(app)
            app.textplot.Text = string(app.times(app.current_frame));
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dataStruct, params)
            app.params = params;
            
            has = [dataStruct.has];
            idx = find([has.blobs]);
            dataStruct = dataStruct(idx);
            app.data = dataStruct;
            timestamps = app.data.get_full_experiment;
            app.TimestampDropDown.Items =cellstr(timestamps);
            app.TimestampDropDown.ItemsData = [1:length(timestamps)]';
            TimestampDropDownValueChanged(app)
        end

        % Value changed function: TimestampDropDown
        function TimestampDropDownValueChanged(app, event)
            idx = app.TimestampDropDown.Value;
%             idx = strcmp(app.TimestampDropDown.ItemsData,app.TimestampDropDown.Value);
            app.current_data = app.data(idx).load_blobsdata;
            app.current_data = app.current_data.raw_blobs;
            
            temp = app.current_data;
            ids = [temp.aniID];
            [C,ia,ic] = unique(ids,'stable');
            id_counts = accumarray(ic,1);
            
            blobsData = temp;
            blobsData = structfun(@(x) mat2cell(x,1,id_counts'),blobsData,'UniformOutput',false);
            fields = fieldnames(blobsData);
            
            aniData = struct();
            for ii = 1:length(fields)
                for jj = 1:length(blobsData.(fields{ii}))
                    aniData(jj,1).(fields{ii}) = blobsData.(fields{ii}){jj};
                end
            end
            
            duration = cellfun(@range, {aniData.time});
            filt = duration >= app.params.config_choreography.t;
            aniData = aniData(filt);
            
            app.current_data = aniData;
            app.current_ani = aniData(1);
            app.current_frame = 1;
            
            app.ObjectnumberDropDown.Items = string(unique([app.current_data.aniID]));
            app.ObjectnumberDropDown.ItemsData = [1:length(app.ObjectnumberDropDown.Items)]';
            app.ObjectnumberDropDown.Value = 1;
            ObjectnumberDropDownValueChanged(app)
            
            cla(app.UIAxes)
            app.mainplot = plot(app.UIAxes,0,0);
            hold(app.UIAxes,'on')
            app.textplot = text(app.UIAxes,0.8,0.9,'0.0','Units','normalized','FontSize',20);
            hold(app.UIAxes,'off')
%             app.textplot = 
%             update_main_plot(app,app.mainplot,app.current_ani,app.current_frame);
            update_slider(app);
        end

        % Value changed function: ObjectnumberDropDown
        function ObjectnumberDropDownValueChanged(app, event)
            value = app.ObjectnumberDropDown.Value;
            app.current_ani = app.current_data(value);
            app.times = [app.current_ani.time];
            app.current_frame = 1;
%             app.TimeSlider
            update_slider(app);
%             update_main_plot(app,app.mainplot,app.current_ani,app.current_frame);
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            value = app.TimeSlider.Value;
            [~,idx] = min(abs([app.current_ani.time]-value));
            app.current_frame = idx;
            update_main_plot(app,app.mainplot,app.current_ani,app.current_frame);
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            while app.PlayButton.Value & (app.current_frame < length(app.times))
                update_main_plot(app,app.mainplot,app.current_ani,app.current_frame);
                app.TimeSlider.Value = app.times(app.current_frame);
                app.current_frame = app.current_frame+1;
                pause(1/35)
            end
        end

        % Value changed function: BackButton
        function BackButtonValueChanged(app, event)
            while app.BackButton.Value & (app.current_frame>1)
                app.current_frame = app.current_frame-1;
                update_main_plot(app,app.mainplot,app.current_ani,app.current_frame);
                app.TimeSlider.Value = app.times(app.current_frame);
                pause(1/35)
            end
        end

        % Button pushed function: ForwardOne
        function ForwardOneButtonPushed(app, event)
            if (app.current_frame <= length(app.times))
                app.current_frame = app.current_frame+1;
                update_main_plot(app,app.mainplot,app.current_ani,app.current_frame+1);
                app.TimeSlider.Value = app.times(app.current_frame+1);
            end
        end

        % Button pushed function: BackOne
        function BackOneButtonPushed(app, event)
            if app.current_frame>=1
                update_main_plot(app,app.mainplot,app.current_ani,app.current_frame-1);
                app.TimeSlider.Value = app.times(app.current_frame-1);
                app.current_frame = app.current_frame-1;
            end
        end

        % Callback function
        function StartFrameButtonPushed(app, event)
            idx = strcmp({app.BehaviourButtonGroup.Buttons.Text}',app.BehaviourButtonGroup.SelectedObject.Text);
            val = app.scores(find(idx),app.current_frame);
            switch val
                case 1
                    app.scores(find(idx),app.current_frame) = 0;
                case 0 
                    app.scores(find(idx),app.current_frame) = 1;
            end
            update_scores(app)
            update_score_plot(app)
        end

        % Callback function
        function EndFrameButtonPushed(app, event)
            idx = strcmp({app.BehaviourButtonGroup.Buttons.Text}',app.BehaviourButtonGroup.SelectedObject.Text);
            val = app.scores(find(idx),app.current_frame);
            switch val
                case 1
                    app.scores(find(idx),app.current_frame) = 0;
                case 0 
                    app.scores(find(idx),app.current_frame) = 1;
            end
            update_scores(app)
            update_score_plot(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {491, 491, 491};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {491, 491};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {211, '1x', 225};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ObjectnumberDropDownLabel = uilabel(app.LeftPanel);
            app.ObjectnumberDropDownLabel.Text = 'Object number';
            app.ObjectnumberDropDown = uidropdown(app.LeftPanel);
            app.ObjectnumberDropDown.ValueChangedFcn = createCallbackFcn(app, @ObjectnumberDropDownValueChanged, true);
            app.TimestampDropDownLabel = uilabel(app.LeftPanel);
            app.TimestampDropDownLabel.Text = 'Timestamp';
            app.TimestampDropDown = uidropdown(app.LeftPanel);
            app.TimestampDropDown.ValueChangedFcn = createCallbackFcn(app, @TimestampDropDownValueChanged, true);
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TimeSliderLabel = uilabel(app.CenterPanel);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.CenterPanel);
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.BehaviourButtonGroup = uibuttongroup(app.RightPanel);
            app.BehaviourButtonGroup.Title = 'Behaviour';
            app.RollButton = uiradiobutton(app.BehaviourButtonGroup);
            app.RollButton.Text = 'Roll';
            app.RollButton.Value = true;
            app.CBendButton = uiradiobutton(app.BehaviourButtonGroup);
            app.CBendButton.Text = 'C-Bend';
            app.BehaviourButton = uiradiobutton(app.BehaviourButtonGroup);
            app.BehaviourButton.Text = 'Behaviour';
            app.BehaviournameEditFieldLabel = uilabel(app.RightPanel);
            app.BehaviournameEditFieldLabel.Text = 'Behaviour name';
            app.BehaviournameEditField = uieditfield(app.RightPanel, 'text');
            app.AddBehaviourButton = uibutton(app.RightPanel, 'push');
            app.AddBehaviourButton.Text = 'Add Behaviour';
            app.PlayButton = uibutton(app.RightPanel, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.BackButton = uibutton(app.RightPanel, 'state');
            app.BackButton.ValueChangedFcn = createCallbackFcn(app, @BackButtonValueChanged, true);
            app.BackButton.Text = 'Back';
            app.ForwardOne = uibutton(app.RightPanel, 'push');
            app.ForwardOne.ButtonPushedFcn = createCallbackFcn(app, @ForwardOneButtonPushed, true);
            app.ForwardOne.Text = '+1';
            app.BackOne = uibutton(app.RightPanel, 'push');
            app.BackOne.ButtonPushedFcn = createCallbackFcn(app, @BackOneButtonPushed, true);
            app.BackOne.Text = '-1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaggotScorer_quick(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end